classdef inputInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegionDropDownLabel             matlab.ui.control.Label
        RegionDropDown                  matlab.ui.control.DropDown
        SubregionDropDownLabel          matlab.ui.control.Label
        SubregionDropDown               matlab.ui.control.DropDown
        UrbanRuralDropDownLabel         matlab.ui.control.Label
        UrbanRuralDropDown              matlab.ui.control.DropDown
        ClimateDropDownLabel            matlab.ui.control.Label
        ClimateDropDown                 matlab.ui.control.DropDown
        HousingTypeDropDownLabel        matlab.ui.control.Label
        HousingTypeDropDown             matlab.ui.control.DropDown
        ConstructionYearDropDownLabel   matlab.ui.control.Label
        ConstructionYearDropDown        matlab.ui.control.DropDown
        TotalSquareFootageDropDownLabel  matlab.ui.control.Label
        TotalSquareFootageDropDown      matlab.ui.control.DropDown
        NumberofHouseholdMembersDropDownLabel  matlab.ui.control.Label
        NumberofHouseholdMembersDropDown  matlab.ui.control.DropDown
        EnergyPaymentMethodDropDownLabel  matlab.ui.control.Label
        EnergyPaymentMethodDropDown     matlab.ui.control.DropDown
        MainHeatingFuelDropDownLabel    matlab.ui.control.Label
        MainHeatingFuelDropDown         matlab.ui.control.DropDown
        HouseholdIncomeEditFieldLabel   matlab.ui.control.Label
        HouseholdIncomeEditField        matlab.ui.control.NumericEditField
        UserInformationLabel            matlab.ui.control.Label
        EstimatedEnergyUseEditField     matlab.ui.control.EditField
        CalculateMyScoreButton          matlab.ui.control.Button
        EstimatedEnergyUsageLabel       matlab.ui.control.Label
        EnergyScoreLabel                matlab.ui.control.Label
        Score                           matlab.ui.control.EditField
        AnnualEnergyBillEditFieldLabel  matlab.ui.control.Label
        AnnualEnergyBillEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        householdIncomeCategories = "NA"; % Description
        energyConsumption % Description
        energyExpendatures % Description
    end
    
    methods (Access = private)
        
        function householdCalc(app)
            if (app.HouseholdIncomeEditField.Value < 20000)
                app.householdIncomeCategories = 'Less than $20,000';
            elseif(app.HouseholdIncomeEditField.Value < 40000)
                app.householdIncomeCategories = '$20,000 to $39,999';
            elseif(app.HouseholdIncomeEditField.Value < 60000)
                app.householdIncomeCategories = '$40,000 to $59,999';
            elseif(app.HouseholdIncomeEditField.Value < 80000)
                app.householdIncomeCategories = '$60,000 to $79,999';
            elseif(app.HouseholdIncomeEditField.Value < 100000)
                app.householdIncomeCategories = '$80,000 to $99,999';
            elseif(app.HouseholdIncomeEditField.Value < 120000)
                app.householdIncomeCategories = '$100,000 to $119,999';
            elseif(app.HouseholdIncomeEditField.Value < 140000)
                app.householdIncomeCategories = '$120,000 to $139,999';
            else
                app.householdIncomeCategories = '$140,000 or more';
            end
            
            
        end
        
        
        function [energyConsumption, energyExpendatures] = findSpec(app)
            region = app.RegionDropDown.Value;
            subregion = app.SubregionDropDown.Value;
            urban = app.UrbanRuralDropDown.Value;
            climate = app.ClimateDropDown.Value;
            housingtype =  app.HousingTypeDropDown.Value;
            sqft =  app.TotalSquareFootageDropDown.Value;
            yearcst = app.ConstructionYearDropDown.Value;
            hshld = app.NumberofHouseholdMembersDropDown.Value;
            income = app.householdIncomeCategories;
            included_in_rent= app.EnergyPaymentMethodDropDown.Value;
            heating_fuel = app.MainHeatingFuelDropDown.Value;
            
            
            switch region
                case 'Midwest'
                    T = readtable('Midwest.xlsx');
                case 'South'
                    T = readtable('South.xlsx');
                case 'Northeast'
                    T = readtable('Northeast.xlsx');
                case 'West'
                    T = readtable('West.xlsx');
            end
            
            energyConsumptionT = T.EnergyConsumption;
            % Per household member (million Btu)
            
            energyExpendaturesT = T.EnergyExpenditures;
            %Per household member (dollars)
            
            myKeys = string({region; subregion; urban; climate; housingtype; sqft; yearcst; hshld; income; included_in_rent; heating_fuel});
            idx = ismember(T.Tags, myKeys);
            % Access the table for these specific rows
            
            energyConsumption = mean(energyConsumptionT(idx));
            energyExpendatures = mean(energyExpendaturesT(idx));
        end
        
    end
    

    methods (Access = private)

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            value = app.RegionDropDown.Value;
            if (value == "Midwest")
                app.SubregionDropDown.Items = {'East North Central', 'West North Central'};
                app.SubregionDropDown.Value = 'East North Central';
            end
            if(value == "Northeast")
                app.SubregionDropDown.Items = {'New England', 'Middle Atlantic'};
                app.SubregionDropDown.Value = 'New England';
            end
            if(value == "South")
                app.SubregionDropDown.Items = {'South Atlantic', 'East South Central', 'West South Central'};
                app.SubregionDropDown.Value = 'South Atlantic';
            end
            if(value == "West")
                app.SubregionDropDown.Items = {'Mountain North', 'Mountain South', 'Pacific'};
                app.SubregionDropDown.Value = 'Mountain North';
            end
        end

        % Value changed function: HouseholdIncomeEditField
        function HouseholdIncomeEditFieldValueChanged(app, event)
            value = app.HouseholdIncomeEditField.Value;
            householdCalc(app);
            
        end

        % Callback function: CalculateMyScoreButton, SubregionDropDown
        function CalculateMyScoreButtonPushed(app, event)
            
            [energyconsumption,energyexpenses] = findSpec(app);
            app.EstimatedEnergyUseEditField.Value = num2str(energyconsumption) + " million BTU per year";
            app.Score.Value = num2str (50+ round((energyexpenses - app.AnnualEnergyBillEditField.Value)/energyexpenses *50));
% fig = uifigure;
% txa = uitextarea(fig,...
%     'Value', {num2str(energyconsumption)});
%                 'Value', {string(a);...
%                 'Address 1'; 'Address 2';'City, State'; 'Postal Code'});
%             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RegionDropDownLabel = uilabel(app.UIFigure);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.UIFigure);
            app.RegionDropDown.Items = {'Please Select', 'Midwest', 'South', 'Northeast', 'West'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = 'Please Select';
            app.SubregionDropDownLabel = uilabel(app.UIFigure);
            app.SubregionDropDownLabel.Text = 'Subregion';
            app.SubregionDropDown = uidropdown(app.UIFigure);
            app.SubregionDropDown.Items = {};
            app.SubregionDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateMyScoreButtonPushed, true);
            app.SubregionDropDown.Value = {};
            app.UrbanRuralDropDownLabel = uilabel(app.UIFigure);
            app.UrbanRuralDropDownLabel.Text = 'Urban/Rural';
            app.UrbanRuralDropDown = uidropdown(app.UIFigure);
            app.UrbanRuralDropDown.Items = {'Urbanized Area', 'Urban Cluster', 'Rural'};
            app.UrbanRuralDropDown.Value = 'Urbanized Area';
            app.ClimateDropDownLabel = uilabel(app.UIFigure);
            app.ClimateDropDownLabel.Text = 'Climate';
            app.ClimateDropDown = uidropdown(app.UIFigure);
            app.ClimateDropDown.Items = {'Very cold/Cold', 'Mixed-humid', 'Mixed-dry/Hot-dry', 'Hot-humid', 'Marine'};
            app.ClimateDropDown.Value = 'Very cold/Cold';
            app.HousingTypeDropDownLabel = uilabel(app.UIFigure);
            app.HousingTypeDropDownLabel.Text = 'Housing Type';
            app.HousingTypeDropDown = uidropdown(app.UIFigure);
            app.HousingTypeDropDown.Items = {'Single-family detached', 'Single-family attached', 'Apartments in buildings with 2–4 units', 'Apartments in buildings with 5 or more units', 'Mobile homes'};
            app.HousingTypeDropDown.Value = 'Single-family detached';
            app.ConstructionYearDropDownLabel = uilabel(app.UIFigure);
            app.ConstructionYearDropDownLabel.Text = 'Construction Year';
            app.ConstructionYearDropDown = uidropdown(app.UIFigure);
            app.ConstructionYearDropDown.Items = {'Before 1950', '1950 to 1959', '1960 to 1969', '1970 to 1979', '1980 to 1989', '1990 to 1999', '2000 to 2009', '2010 to 2015'};
            app.ConstructionYearDropDown.Value = 'Before 1950';
            app.TotalSquareFootageDropDownLabel = uilabel(app.UIFigure);
            app.TotalSquareFootageDropDownLabel.Text = 'Total Square Footage';
            app.TotalSquareFootageDropDown = uidropdown(app.UIFigure);
            app.TotalSquareFootageDropDown.Items = {'Fewer than 1,000', '1,000 to 1,499', '1,500 to 1,999', '2,000 to 2,499', '2,500 to 2,999', '3,000 or greater'};
            app.TotalSquareFootageDropDown.Value = 'Fewer than 1,000';
            app.NumberofHouseholdMembersDropDownLabel = uilabel(app.UIFigure);
            app.NumberofHouseholdMembersDropDownLabel.Text = 'Number of Household Members';
            app.NumberofHouseholdMembersDropDown = uidropdown(app.UIFigure);
            app.NumberofHouseholdMembersDropDown.Items = {'1 member', '2 members', '3 members', '4 members', '5 members', '6 or more members'};
            app.NumberofHouseholdMembersDropDown.Value = '1 member';
            app.EnergyPaymentMethodDropDownLabel = uilabel(app.UIFigure);
            app.EnergyPaymentMethodDropDownLabel.Text = 'Energy Payment Method';
            app.EnergyPaymentMethodDropDown = uidropdown(app.UIFigure);
            app.EnergyPaymentMethodDropDown.Items = {'All paid by household', 'Some paid by household, some included in rent or condo fee', 'All included in rent or condo fee', 'Some other method'};
            app.EnergyPaymentMethodDropDown.Value = 'All paid by household';
            app.MainHeatingFuelDropDownLabel = uilabel(app.UIFigure);
            app.MainHeatingFuelDropDownLabel.Text = 'Main Heating Fuel';
            app.MainHeatingFuelDropDown = uidropdown(app.UIFigure);
            app.MainHeatingFuelDropDown.Items = {'Natural gas', 'Electricity', 'Fuel oil/kerosene', 'Propane'};
            app.MainHeatingFuelDropDown.Value = 'Natural gas';
            app.HouseholdIncomeEditFieldLabel = uilabel(app.UIFigure);
            app.HouseholdIncomeEditFieldLabel.Text = 'Household Income';
            app.HouseholdIncomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.HouseholdIncomeEditField.Limits = [0 Inf];
            app.HouseholdIncomeEditField.ValueChangedFcn = createCallbackFcn(app, @HouseholdIncomeEditFieldValueChanged, true);
            app.HouseholdIncomeEditField.Value = 60000;
            app.UserInformationLabel = uilabel(app.UIFigure);
            app.UserInformationLabel.Text = 'User Information';
            app.EstimatedEnergyUseEditField = uieditfield(app.UIFigure, 'text');
            app.EstimatedEnergyUseEditField.Value = '  ';
            app.CalculateMyScoreButton = uibutton(app.UIFigure, 'push');
            app.CalculateMyScoreButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateMyScoreButtonPushed, true);
            app.CalculateMyScoreButton.Text = 'Calculate My Score';
            app.EstimatedEnergyUsageLabel = uilabel(app.UIFigure);
            app.EstimatedEnergyUsageLabel.Text = 'Estimated Energy Usage';
            app.EnergyScoreLabel = uilabel(app.UIFigure);
            app.EnergyScoreLabel.Text = 'Energy Score';
            app.Score = uieditfield(app.UIFigure, 'text');
            app.Score.Value = '  ';
            app.AnnualEnergyBillEditFieldLabel = uilabel(app.UIFigure);
            app.AnnualEnergyBillEditFieldLabel.Text = 'Annual Energy Bill';
            app.AnnualEnergyBillEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = inputInfo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end